classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        functionsDropDown       matlab.ui.control.DropDown
        functionsDropDownLabel  matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        funcs = {@(x) sin(x), @(x) cos(x), @(x) tan(x), @(x) x, @(x) log(x), @(x) exp(x), @(x) x.^2, @(x) x.^3, @(x) 1./cos(x), @(x) 1./sin(x)};
        funcTitles = ["sin(x)", "cos(x)", "tan(x)", "x", "log(x)", "exp(x)", "cuadratica(x)", "cubica(x)", "sec(x)", "csc(x)"];
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: functionsDropDown, functionsDropDown
        function UIAxesButtonDown(app, event)
            selectedFunction = app.functionsDropDown.Value;
            selectedFunctionIndex = strcmp(app.funcTitles, selectedFunction);
            func = app.funcs{selectedFunctionIndex};
            funcName = selectedFunction;
            x = linspace(-10, 10, 1000);
            y = func(x);
            title(app.UIAxes, funcName);
            plot(app.UIAxes, x, y);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.functionsDropDownLabel = uilabel(app.UIFigure);
            app.functionsDropDownLabel.Text = 'functions';
            app.functionsDropDown = uidropdown(app.UIFigure);
            app.functionsDropDown.Items = {'sin(x)', 'cos(x)', 'tan(x)', 'x', 'log(x)', 'exp(x)', 'cuadratica(x)', 'cubica(x)', 'sec(x)', 'csc(x)'};
            app.functionsDropDown.ValueChangedFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.functionsDropDown.ClickedFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.functionsDropDown.Value = 'sin(x)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end