classdef signal_generator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SealDropDownLabel            matlab.ui.control.Label
        Sign                         matlab.ui.control.DropDown
        AmplitudKnob_2Label          matlab.ui.control.Label
        Amplitud                     matlab.ui.control.Knob
        BaseLabel                    matlab.ui.control.Label
        Base                         matlab.ui.control.Knob
        CicloKnob_2Label             matlab.ui.control.Label
        Ciclo                        matlab.ui.control.Knob
        FrecuenciaKnob_2Label        matlab.ui.control.Label
        Frecuencia                   matlab.ui.control.Knob
        AnchoKnob_2Label             matlab.ui.control.Label
        Ancho                        matlab.ui.control.Knob
        RepresentacinLabel           matlab.ui.control.Label
        RepresentacionDrop           matlab.ui.control.DropDown
        FrecuenciaKnob_2Label_2      matlab.ui.control.Label
        FrecuenciaD                  matlab.ui.control.Knob
        EscaladetiempoLabel          matlab.ui.control.Label
        Representacion               matlab.ui.control.Slider
        ComponenteLabel              matlab.ui.control.Label
        Componente                   matlab.ui.control.DropDown
        OperacionessobrelavariableindependienteLabel  matlab.ui.control.Label
        ReflexionSwitchLabel         matlab.ui.control.Label
        ReflexionSwitch              matlab.ui.control.Switch
        FactordeEscalamientoSpinnerLabel  matlab.ui.control.Label
        FactordeEscalamientoSpinner  matlab.ui.control.Spinner
        FactordeCorrimientoSpinnerLabel  matlab.ui.control.Label
        FactordeCorrimientoSpinner   matlab.ui.control.Spinner
        UIAxes                       matlab.ui.control.UIAxes
    end

    methods (Access = private)
    function addLibToPath(app)
        addpath(fullfile(app.PathToApp, 'lib'));
    end
    
    
    function plotGraphOddPairSegments(app, n, x)
        xe = 1/2 * (x + fliplr(x));
        xo = 1/2 * (x - fliplr(x));

        if app.Componente.Value == "Par"
            % Check if the plot type is stem or plot
            if app.RepresentacionDrop.Value == "Discreta"
                stem(n, xe, 'cyan'), grid on
            elseif app.RepresentacionDrop.Value == "Continua"
                plot(n, xe, 'cyan'), grid on
            end
        end

        if app.Componente.Value == "Impar"
            % Check if the plot type is stem or plot
            if app.RepresentacionDrop.Value == "Discreta"
                stem(n, xo, 'red'), grid on
            elseif app.RepresentacionDrop.Value == "Continua"
                plot(n, xo, 'red'), grid on
            end
        end

        if app.Componente.Value == "Suma"
            xs = xe + xo;
            % Check if the plot type is stem or plot
            if app.RepresentacionDrop.Value == "Discreta"
                stem(n, xs, 'green'), grid on
            elseif app.RepresentacionDrop.Value == "Continua"
                plot(n, xs, 'green'), grid on
            end
        end
    end
        
    function plotGraph(app, n, x, x_label, y_label, graphTitle)
        % Check if the plot type is stem or plot
        if app.RepresentacionDrop.Value == "Discreta"
            % Plot a stem graph
            stem(n, x),
        elseif app.RepresentacionDrop.Value == "Continua"
            % Plot a regular line graph
            plot(n, x),
        end
         grid on,
            xlabel(x_label), ylabel(y_label), title(graphTitle)
        
        plotGraphOddPairSegments(app, n, x)
     
        end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, as)
            
        end

        % Callback function
        function AmplitudSliderValueChanged(app, event)
            
        end

        % Value changed function: Amplitud, Ancho, Base, Ciclo, 
        % Componente, FactordeCorrimientoSpinner, 
        % FactordeEscalamientoSpinner, Frecuencia, FrecuenciaD, 
        % ReflexionSwitch, Representacion, RepresentacionDrop, Sign
        function SignValueChanged(app, event)
            title(app.UIAxes,app.Sign.Value);
            cla(app.UIAxes);
            
            % Operaciones sobre variable independiente
            factorEscalamientoTiempo = app.FactordeEscalamientoSpinner.Value;
            
            if app.ReflexionSwitch.Value == "On"
                factorEscalamientoTiempo = -factorEscalamientoTiempo;
            end
                
            corrimiento = app.FactordeCorrimientoSpinner.Value;
            
            fs = 1000; % frecuencia fundamental para senales continuas
            
            if app.RepresentacionDrop.Value == "Discreta"
                fs = 1;
            end

            A = app.Amplitud.Value; %Amplitud
            f = app.Frecuencia.Value; %Frecuencia Fundamental
            duty = app.Ciclo.Value; %Ciclo útil
            width = app.Ancho.Value; %Ancho
            
            T = app.Representacion.Value * (1/f); %Periodos a graficar
            t = 0:1/fs:T;
            t = t * factorEscalamientoTiempo + corrimiento;
            
            r = app.Base.Value;
                        
            switch app.Sign.Value
                case "Cuadrada"
                    fx = A*square(2*pi*f*t,duty); %Generación de Señal
                    plotGraph(app, t, fx, "", "", "")
                case "Diente de sierra"
                    fx = A*sawtooth(2*pi*f*t,width);
                    plotGraph(app, t, fx, "", "", "")
                case "Senoidal"
                    fx =  A*sin(2*pi*f*t+phi);
                    plotGraph(app, t, fx, "", "", "")
                case "Exponencial creciente y decreciente"
                    fx1 = exp(t);
                    fx2 = r.^(-t);
                    subplot(2, 1, 1), plotGraph(app, t, fx1, "", "", "");
                    subplot(2, 1, 2), plotGraph(app, t, fx2, "", "", "");
                case "Exponencial compleja"
                    fx = exp(1j.*(2.*t+(pi/4)));
                    xRE = real(fx); %Cálculo de la parte Real de x(t)
                    xIM = imag(fx); %Cálculo de la parte Imaginaria de x(t)
                    xMAG = abs(fx); %Cálculo de la Magnitud de x(t)
                    xARG = atan(imag(fx)./real(fx)); %Cálculo del Argumento de x(t)
                    subplot(2,2,1), plotGraph(app, t, xRE, "Tiempo(s)", "Amplitud", "Parte Re de fx(t)");
                    subplot(2,2,2), plotGraph(app, t, xIM, "Tiempo(s)", "Amplitud", "Parte Im de fx(t)");
                    subplot(2,2,3), plotGraph(app, t, xMAG, "Tiempo(s)", "Amplitud", "Parte xMAG de fx(t)");
                    subplot(2,2,4), plotGraph(app, t, xARG, "Tiempo(s)", "Amplitud", "Parte XArg de fx(t)");
                    hold off;
                case "Escalón"
                    x1 = A*heaviside(t); %Generación de Señal
                    x2 = A.*ones(1,length(t)); %Generación de Señal
                    x3 = A.*ustep(t); %Generación de Señal ADSP Toolbox
                    subplot(2, 2, 1), plotGraph(app, t, x1, "Tiempo(s)", "Amplitud", "Escalon con heaviside");
                    subplot(2, 2, 2), plotGraph(app, t, x2, "Tiempo(s)", "Amplitud", "Escalon con ones");
                    subplot(2, 2, 3), plotGraph(app, t, x3, "Tiempo(s)", "Amplitud", "Escalon con ustep");
                case "Impulso"
                    t = 0:5;
                    t = t * factorEscalamientoTiempo + corrimiento;
                    x1 = A.*[zeros(1,2), ones(1,1), zeros(1,3)];
                    plotGraph(app, t, x1, "", "", "");
                case "Rampa"
                    t1 = 0:1/fs:5;
                    t1 = t1 * factorEscalamientoTiempo + corrimiento;
                    t2 = 5:1/fs:10;
                    t2 = t2 * factorEscalamientoTiempo + corrimiento;
                    r = t1;
                    t = [t1, t2];
                    x1 =  A.*[zeros(1,length(t1)),r];
                    plotGraph(app, t, x1, "", "", "");
                case "Rectangular"
                    t = -1:1/fs:1;
                    t = t * factorEscalamientoTiempo + corrimiento;
                    x = A.*rectpuls(t);
                    plotGraph(app, t, x, "Tiempo (s)", "Amplitud", "Rectangular con rectpuls");
                case "Triangular"
                    t = -2:1/fs:2;
                    t = t * factorEscalamientoTiempo + corrimiento;
                    x = A*tripuls(t);
                    plotGraph(app, t, x, "Tiempo(s)", "Amplitud", "Triangular con tripuls");
                
            end
            
        end

        % Drop down opening function: Sign
        function SignDropDownOpening(app, event)
            
        end

        % Callback function
        function RepresentacionDropValueChanged(app, event)
        
        end

        % Callback function
        function BaseValueChanged(app, event)
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SealDropDownLabel = uilabel(app.UIFigure);
            app.SealDropDownLabel.Text = 'Señal';
            app.Sign = uidropdown(app.UIFigure);
            app.Sign.Items = {'Cuadrada', 'Diente de sierra', 'Senoidal', 'Exponencial creciente y decreciente', 'Exponencial compleja', 'Escalón', 'Impulso', 'Rampa', 'Rectangular', 'Triangular'};
            app.Sign.DropDownOpeningFcn = createCallbackFcn(app, @SignDropDownOpening, true);
            app.Sign.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.Sign.Value = 'Cuadrada';
            app.AmplitudKnob_2Label = uilabel(app.UIFigure);
            app.AmplitudKnob_2Label.Text = 'Amplitud';
            app.Amplitud = uiknob(app.UIFigure, 'continuous');
            app.Amplitud.Limits = [0 2];
            app.Amplitud.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.Amplitud.Value = 1;
            app.BaseLabel = uilabel(app.UIFigure);
            app.BaseLabel.Text = 'Base';
            app.Base = uiknob(app.UIFigure, 'continuous');
            app.Base.Limits = [-5 5];
            app.Base.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.Base.Value = 1;
            app.CicloKnob_2Label = uilabel(app.UIFigure);
            app.CicloKnob_2Label.Text = 'Ciclo';
            app.Ciclo = uiknob(app.UIFigure, 'continuous');
            app.Ciclo.Limits = [50 100];
            app.Ciclo.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.Ciclo.Value = 50;
            app.FrecuenciaKnob_2Label = uilabel(app.UIFigure);
            app.FrecuenciaKnob_2Label.Text = 'Frecuencia';
            app.Frecuencia = uiknob(app.UIFigure, 'continuous');
            app.Frecuencia.Limits = [0.1 10];
            app.Frecuencia.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.Frecuencia.Value = 5;
            app.AnchoKnob_2Label = uilabel(app.UIFigure);
            app.AnchoKnob_2Label.Text = 'Ancho';
            app.Ancho = uiknob(app.UIFigure, 'continuous');
            app.Ancho.Limits = [0 1];
            app.Ancho.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.RepresentacinLabel = uilabel(app.UIFigure);
            app.RepresentacinLabel.Text = 'Representación';
            app.RepresentacionDrop = uidropdown(app.UIFigure);
            app.RepresentacionDrop.Items = {'Continua', 'Discreta', 'Periodica', 'Aperiodica'};
            app.RepresentacionDrop.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.RepresentacionDrop.Value = 'Continua';
            app.FrecuenciaKnob_2Label_2 = uilabel(app.UIFigure);
            app.FrecuenciaKnob_2Label_2.Text = 'FrecuenciaD';
            app.FrecuenciaD = uiknob(app.UIFigure, 'continuous');
            app.FrecuenciaD.Limits = [0.1 1];
            app.FrecuenciaD.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.FrecuenciaD.Value = 0.1;
            app.EscaladetiempoLabel = uilabel(app.UIFigure);
            app.EscaladetiempoLabel.Text = 'Escala de tiempo';
            app.Representacion = uislider(app.UIFigure);
            app.Representacion.Limits = [0 20];
            app.Representacion.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.Representacion.Value = 10;
            app.ComponenteLabel = uilabel(app.UIFigure);
            app.ComponenteLabel.Text = 'Componente';
            app.Componente = uidropdown(app.UIFigure);
            app.Componente.Items = {'Seleccion', 'Par', 'Impar', 'Suma'};
            app.Componente.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.Componente.Value = 'Seleccion';
            app.OperacionessobrelavariableindependienteLabel = uilabel(app.UIFigure);
            app.OperacionessobrelavariableindependienteLabel.Text = 'Operaciones sobre la variable independiente';
            app.ReflexionSwitchLabel = uilabel(app.UIFigure);
            app.ReflexionSwitchLabel.Text = 'Reflexion';
            app.ReflexionSwitch = uiswitch(app.UIFigure, 'slider');
            app.ReflexionSwitch.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.FactordeEscalamientoSpinnerLabel = uilabel(app.UIFigure);
            app.FactordeEscalamientoSpinnerLabel.Text = 'Factor de Escalamiento';
            app.FactordeEscalamientoSpinner = uispinner(app.UIFigure);
            app.FactordeEscalamientoSpinner.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.FactordeEscalamientoSpinner.Value = 1;
            app.FactordeCorrimientoSpinnerLabel = uilabel(app.UIFigure);
            app.FactordeCorrimientoSpinnerLabel.Text = 'Factor de Corrimiento';
            app.FactordeCorrimientoSpinner = uispinner(app.UIFigure);
            app.FactordeCorrimientoSpinner.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = signal_generator(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end